classdef func2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        StartStopButton              matlab.ui.control.Button
        PlotControlPanel             matlab.ui.container.Panel
        ShowAmpsCheckBox             matlab.ui.control.CheckBox
        ShowUnitsCheckBox            matlab.ui.control.CheckBox
        PlotWidthMinutesSliderLabel  matlab.ui.control.Label
        PlotWidthMinutesSlider       matlab.ui.control.Slider
        StartFromDatePickerLabel     matlab.ui.control.Label
        StartFromDatePicker          matlab.ui.control.DatePicker
        ValuesPanel                  matlab.ui.container.Panel
        ShowOnlyLastValueCheckBox    matlab.ui.control.CheckBox
        TimeEditFieldLabel           matlab.ui.control.Label
        TimeEditField                matlab.ui.control.EditField
        UnitsEditFieldLabel          matlab.ui.control.Label
        UnitsEditField               matlab.ui.control.NumericEditField
        AmpsEditFieldLabel           matlab.ui.control.Label
        AmpsEditField                matlab.ui.control.NumericEditField
        RightPanel                   matlab.ui.container.Panel
        ClearButton                  matlab.ui.control.Button
        DrawButton                   matlab.ui.control.Button
        DynamicAxisCheckBox          matlab.ui.control.CheckBox
        ForeCastButton               matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function [x, y] = Data(app, run)
            
            Channel = 902207;
            ReadKey = 'KGII51BJTFRFHIID';
            try
                if run == true
                    % 50000 minutes of Data for 1st Time run
                    data = thingSpeakRead(Channel, ...
                        'DateRange',[app.StartFromDatePicker.Value, datetime("now")], ...
                        'OutputFormat','TimeTable', 'ReadKey', ReadKey);
                else
                    % Last Entry of Data for other runs
                    data = thingSpeakRead(Channel, 'NumPoints', 1, ...
                        'OutputFormat','timetable', 'ReadKey', ReadKey);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error -- Retrying');
                return;
            end
            
            y = [data.Amp data.Energy];
            y(isnan(y)) = 0;
            x = data.Timestamps;
            
            try
                if app.ShowOnlyLastValueCheckBox.Value == true
                    app.TimeEditField.Value = string(x(length(x)));
                    app.AmpsEditField.Value = y(length(y(:, 1)));
                    app.UnitsEditField.Value = y(length(y(:, 2)));
                end
            catch
            end
            
        end
        
        function Plot(app, x, y)  % f=forecast data
            global Stop Running;
            
            h = animatedline(app.UIAxes, 'Color', 'r');
            j = animatedline(app.UIAxes, 'Color', 'b');
            
            for k = 1:length(x)
                % Stop if Stop Button is Pushed
                if Stop == false
                    while(1)
                        pause(0.001);
                        Running = false;
                        if Stop == true
                            break;
                        end
                    end
                end
                
                % Fill Values
                
                if app.ShowOnlyLastValueCheckBox.Value == false
                    app.TimeEditField.Value = string(x(k));
                    app.AmpsEditField.Value = y(k, 1);
                    app.UnitsEditField.Value = y(k, 2);
                end
                
                % Draw Plot
                try
                    if app.ShowAmpsCheckBox.Value == true
                        addpoints(h, datenum(x(k)), y(k, 1));
                    else
                        clearpoints(h);
                    end
                    if app.ShowUnitsCheckBox.Value == true
                        addpoints(j, datenum(x(k)), y(k, 2));
                    else
                        clearpoints(j);
                    end
                catch
                end
                range = app.PlotWidthMinutesSlider.Value;
                xlim(app.UIAxes,[datenum(x(k))-range*6.9444e-04 datenum(x(k))])
                drawnow;
                dynamicDateTicks(app.UIAxes);
            end
            
        end
        
        function RunForever(app)
            global Clear;
            Clear = false;
            
            while(1)
                [x, y] = Data(app, false);
                Plot(app, x, y);
                if Clear == true
                    break;
                end
            end
        end
        
        function main(app)
            global Stop Running;
            Stop = true;
            Running = true;


            [x, y] = Data(app, true); % For 1st Time run
            Plot(app, x, y);
            
            RunForever(app); % For Other Runs
        end
        
        function my_closereq(~, fig)
            selection = uiconfirm(fig,'Code is Executing...','Confirm Close',...
                'Icon','warning');
            switch selection
                case 'OK'
                    delete(fig)
                case 'Cancel'
                    return
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Intance Data
            app.StartFromDatePicker.Value = datetime('yesterday',"Format","MM/dd/yyyy");
            
            main(app);
        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            global Stop;
            if Stop == true
                Stop = false;
            else % Resume if Pressed and Already Stop
                Stop = true;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            global Running Stop Clear;
            
            cla(app.UIAxes);
            % Reset Axis
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            
            app.DynamicAxisCheckBox.Enable = 'on';
            app.DrawButton.Enable = 'on';
            
            
            Running = false;
            Stop = false;  % Stop it
            Clear = true;
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.DrawButton.Enable = 'off';
            app.DynamicAxisCheckBox.Enable = 'off';
            
            main(app);
        end

        % Value changed function: DynamicAxisCheckBox
        function DynamicAxisCheckBoxValueChanged(app, event)
            value = app.DynamicAxisCheckBox.Value;
            if value == true
                app.DrawButton.Enable = 'off';
                [x, y] = Data(app, true);
                
                if app.ShowAmpsCheckBox.Value == true
                    plot(app.UIAxes, x, y(:, 1), 'Color','r');
                end
                if app.ShowUnitsCheckBox.Value == true
                    plot(app.UIAxes, x, y(:, 2), 'Color','b');
                end
            else
                cla(app.UIAxes, 'reset');
                app.DynamicAxisCheckBox.Enable = 'off';
                app.DrawButton.Enable = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global Running;
            if Running == true
                my_closereq(app, app.UIFigure);
            else   % If animation not running
                ClearButtonPushed(app);
                delete(app.UIFigure)
            end
        end

        % Value changed function: ShowAmpsCheckBox
        function ShowAmpsCheckBoxValueChanged(app, event)
            if app.DynamicAxisCheckBox.Value == true
                cla(app.UIAxes);
                DynamicAxisCheckBoxValueChanged(app);
            elseif (app.ShowUnitsCheckBox.Value == true) || (app.ShowAmpsCheckBox.Value == true)
                cla(app.UIAxes);
                main(app);
            end
        end

        % Value changed function: ShowUnitsCheckBox
        function ShowUnitsCheckBoxValueChanged(app, event)
            if app.DynamicAxisCheckBox.Value == true
                cla(app.UIAxes);
                DynamicAxisCheckBoxValueChanged(app);
            elseif (app.ShowUnitsCheckBox.Value == true) || (app.ShowAmpsCheckBox.Value == true)
                cla(app.UIAxes);
                main(app);
            end
        end

        % Button pushed function: ForeCastButton
        function ForeCastButtonPushed(app, event)
            [x, y] = Data(app, true);
            data = iddata(y(:,1), datenum(x));
            sys = arx(data, [2 2 2]);
            fig = uifigure('Name','SubPlots Window','Position',[100 100 425 275]);
            ax = uiaxes(fig);
            f = forecast(sys,data,30);
            plot(ax,f.OutputData);
            % datetick(ax,'x','dd - HH:MM:SS','keeplimits');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {486, 486};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {276, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartStopButton = uibutton(app.LeftPanel, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.PlotControlPanel = uipanel(app.LeftPanel);
            app.PlotControlPanel.Title = 'Plot Control';
            app.ShowAmpsCheckBox = uicheckbox(app.PlotControlPanel);
            app.ShowAmpsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAmpsCheckBoxValueChanged, true);
            app.ShowAmpsCheckBox.Text = 'Show Amps';
            app.ShowAmpsCheckBox.Value = true;
            app.ShowUnitsCheckBox = uicheckbox(app.PlotControlPanel);
            app.ShowUnitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowUnitsCheckBoxValueChanged, true);
            app.ShowUnitsCheckBox.Text = 'Show Units';
            app.ShowUnitsCheckBox.Value = true;
            app.PlotWidthMinutesSliderLabel = uilabel(app.PlotControlPanel);
            app.PlotWidthMinutesSliderLabel.Text = {'Plot Width'; '(Minutes)'};
            app.PlotWidthMinutesSlider = uislider(app.PlotControlPanel);
            app.PlotWidthMinutesSlider.Limits = [5 120];
            app.PlotWidthMinutesSlider.MajorTicks = [5 15 30 45 60 90 120 1440];
            app.PlotWidthMinutesSlider.MajorTickLabels = {'5', '15', '30', '45', '1h', '1.5h', '2h', '1d'};
            app.PlotWidthMinutesSlider.Value = 10;
            app.StartFromDatePickerLabel = uilabel(app.LeftPanel);
            app.StartFromDatePickerLabel.Text = 'Start From';
            app.StartFromDatePicker = uidatepicker(app.LeftPanel);
            app.ValuesPanel = uipanel(app.LeftPanel);
            app.ValuesPanel.Title = 'Values';
            app.ShowOnlyLastValueCheckBox = uicheckbox(app.ValuesPanel);
            app.ShowOnlyLastValueCheckBox.Text = 'Show Only Last Value';
            app.TimeEditFieldLabel = uilabel(app.ValuesPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.ValuesPanel, 'text');
            app.UnitsEditFieldLabel = uilabel(app.ValuesPanel);
            app.UnitsEditFieldLabel.Text = 'Units';
            app.UnitsEditField = uieditfield(app.ValuesPanel, 'numeric');
            app.AmpsEditFieldLabel = uilabel(app.ValuesPanel);
            app.AmpsEditFieldLabel.Text = 'Amps';
            app.AmpsEditField = uieditfield(app.ValuesPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.ClearButton = uibutton(app.RightPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DrawButton = uibutton(app.RightPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Enable = 'off';
            app.DrawButton.Text = 'Draw';
            app.DynamicAxisCheckBox = uicheckbox(app.RightPanel);
            app.DynamicAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @DynamicAxisCheckBoxValueChanged, true);
            app.DynamicAxisCheckBox.Enable = 'off';
            app.DynamicAxisCheckBox.Text = 'Dynamic Axis';
            app.ForeCastButton = uibutton(app.RightPanel, 'push');
            app.ForeCastButton.ButtonPushedFcn = createCallbackFcn(app, @ForeCastButtonPushed, true);
            app.ForeCastButton.Text = 'ForeCast';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Energy Plot')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amps/Units')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = func2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end